classdef CK_Ten < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        ChnphngphptmChiailpNewtontiptuynEditField  matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel      matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.EditField
        KtqunghimEditField_3Label      matlab.ui.control.Label
        NhpsaischophpEditField         matlab.ui.control.EditField
        NhpsaischophpEditField_3Label  matlab.ui.control.Label
        NhpkhongphnlynghimEditField    matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        DrawButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        ChnphngphpnisuyNisuyNewtonLagrangeEditField  matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel   matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        KtqunisuyEditField             matlab.ui.control.EditField
        KtqunisuyEditField_3Label      matlab.ui.control.Label
        NhpgitrcnnisuyEditField        matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhpdliuyEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        NhpdliuxEditField              matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        DrawButton_2                   matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        nhappphoiquy                   matlab.ui.control.DropDown
        NhpphngphphiquyDropDownLabel   matlab.ui.control.Label
        DrawButton_3                   matlab.ui.control.Button
        kqhoiquy                       matlab.ui.control.EditField
        KtqudonLabel                   matlab.ui.control.Label
        kqpthoiquy                     matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        gtrihoiquy                     matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        yhoiquy                        matlab.ui.control.EditField
        NhpdliuyEditField_2Label       matlab.ui.control.Label
        xhoiquy                        matlab.ui.control.EditField
        NhpdliuxEditField_2Label       matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        nhapppdh                       matlab.ui.control.DropDown
        NhpphngphpohmDropDownLabel     matlab.ui.control.Label
        saisodh                        matlab.ui.control.DropDown
        SaisDropDownLabel              matlab.ui.control.Label
        TnhtheohmsButton               matlab.ui.control.Button
        HocLabel                       matlab.ui.control.Label
        kqdaoham                       matlab.ui.control.EditField
        KtquLabel                      matlab.ui.control.Label
        hsdaoham                       matlab.ui.control.EditField
        NhphmsLabel                    matlab.ui.control.Label
        buocnhaydaoham                 matlab.ui.control.EditField
        NhpbcnhyhLabel                 matlab.ui.control.Label
        ydaoham                        matlab.ui.control.EditField
        NhpdliuyEditField_2Label_2     matlab.ui.control.Label
        xdaoham                        matlab.ui.control.EditField
        NhpdliuxEditField_2Label_2     matlab.ui.control.Label
        TnhtheoXYButton                matlab.ui.control.Button
        TchphnTab                      matlab.ui.container.Tab
        PhngphptchphnDropDown          matlab.ui.control.DropDown
        PhngphptchphnDropDownLabel     matlab.ui.control.Label
        KtqutchphnEditField            matlab.ui.control.EditField
        KtqutchphnEditFieldLabel       matlab.ui.control.Label
        DulieuY_TichphanEditField      matlab.ui.control.EditField
        DliuYEditfieldTexxtLabel       matlab.ui.control.Label
        DulieuX_TichphanEditField      matlab.ui.control.EditField
        DliuXEditfieldTexxtLabel       matlab.ui.control.Label
        Can_TichphanEditField_2        matlab.ui.control.NumericEditField
        NhpcntnhtchphnLabel_2          matlab.ui.control.Label
        Can_TichphanEditField          matlab.ui.control.NumericEditField
        NhpcntnhtchphnLabel            matlab.ui.control.Label
        So_NEditField                  matlab.ui.control.NumericEditField
        NhpNLabel                      matlab.ui.control.Label
        Hamso_TichphanEditField        matlab.ui.control.EditField
        HmsEditfieldtextLabel          matlab.ui.control.Label
        TnhtheoXYButton_2              matlab.ui.control.Button
        TnhtheohmButton                matlab.ui.control.Button
        HoacLabel                      matlab.ui.control.Label
        GiithiuNhmTab                  matlab.ui.container.Tab
        NhimvPanel                     matlab.ui.container.Panel
        GiaHuyLabel                    matlab.ui.control.Label
        PhntchphngiithiuQucMinhLabel   matlab.ui.control.Label
        PhnhiquyohmThnhtLabel          matlab.ui.control.Label
        PhnnghimnisuyChnDuyLabel       matlab.ui.control.Label
        CodeDesignLabel                matlab.ui.control.Label
        BocoWordLabel                  matlab.ui.control.Label
        ThnhvinnhmPanel                matlab.ui.container.Panel
        NhmtrngLabel                   matlab.ui.control.Label
        GiaHuy22207038Label            matlab.ui.control.Label
        NguynVQucMinh22207059Label     matlab.ui.control.Label
        Thnht22207016Label             matlab.ui.control.Label
        NguynLaiChnDuy22207018Label    matlab.ui.control.Label
        GiithiunhmLabel                matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            % Đọc hàm số từ trường nhập liệu
            funcStr = app.NhpphngtrnhEditField.Value;
            try
                f = str2func(['@(x)', funcStr]);  % Chuyển chuỗi thành hàm ẩn danh
            catch
                uialert(app.UIFigure, 'Hàm số nhập không hợp lệ.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc khoảng x từ trường nhập liệu
            rangeStr = app.NhpkhongphnlynghimEditField.Value;
            rangeStr = strrep(rangeStr, ' ', '');  % Xóa khoảng trắng
            range = str2num(rangeStr);  % Chuyển chuỗi thành vector số
            if isempty(range) || length(range) ~= 2
                uialert(app.UIFigure, 'Nhập khoảng x không đúng. Nhập lại dạng "a,b".', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc sai số cho phép từ trường nhập liệu
            toleranceStr = app.NhpsaischophpEditField.Value;
            tolerance = str2double(toleranceStr);
            if isnan(tolerance) || tolerance <= 0
                uialert(app.UIFigure, 'Nhập sai số cho phép không hợp lệ. Sai số phải là một số dương.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc phương pháp tìm nghiệm từ dropdown
            method = app.ChnphngphptmChiailpNewtontiptuynEditField.Value;
            
            % Kiểm tra nhập liệu và vẽ đồ thị
            fplot(app.UIAxes, f, range);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Đồ thị hàm số');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            
            % Tính toán nghiệm và số lần lặp tùy theo phương pháp
            try
                switch method
                    case 'Chia đôi'
                        [root, iterations] = chiadoi(f, range(1), range(2), tolerance);
                    case 'Lặp'
                        initialGuess = (range(1) + range(2)) / 2;
                        [root, iterations] = lap(@(x) x - f(x), initialGuess, tolerance);
                    case 'Newton'
                        initialGuess = (range(1) + range(2)) / 2;
                        [root, iterations] = tieptuyen(f, initialGuess, 0, tolerance);
                    otherwise
                        uialert(app.UIFigure, 'Phương pháp không được hỗ trợ.', 'Lỗi');
                        return;
                end
            catch ME
                uialert(app.UIFigure, ['Có lỗi xảy ra: ', ME.message], 'Lỗi tính toán');
                return;
            end
            
            % Hiển thị kết quả nghiệm và số lần lặp
            app.KtqunghimEditField.Value = num2str(root);
            app.SlnlpEditField.Value = iterations;
            
        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
          % Đọc dữ liệu từ giao diện người dùng
        xa = str2num(app.NhpdliuxEditField.Value);
        ya = str2num(app.NhpdliuyEditField.Value);
        x_value = str2double(app.NhpgitrcnnisuyEditField.Value);  % Giá trị x cần nội suy
        
        % Chọn phương pháp và tính đa thức
        method = app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value;
        switch method
            case 'Newton'
                poly = NewtonInterpolation(xa, ya);
            case 'Lagrange'
                poly = Lagrange(xa, ya);
            otherwise
                uialert(app.UIFigure, 'Phương pháp nội suy không được hỗ trợ.', 'Lỗi');
                return;
        end
        
        % Hiển thị đa thức
        app.KtquathcnisuyEditField.Value = char(poly);
        
        % Đánh giá đa thức tại điểm cụ thể và hiển thị kết quả
        interpolatedValue = double(subs(poly, x_value));
        app.KtqunisuyEditField.Value = num2str(interpolatedValue, '%.5g');
        
        % Vẽ đồ thị
        interpX = linspace(min(xa), max(xa), 100);
        interpY = double(subs(poly, interpX));
        plot(app.UIAxes_2, xa, ya, 'ro');
        hold(app.UIAxes_2, 'on');
        plot(app.UIAxes_2, interpX, interpY, 'b-');
        title(app.UIAxes_2, 'Đồ thị nội suy');
        xlabel(app.UIAxes_2, 'X');
        ylabel(app.UIAxes_2, 'Y');
        legend(app.UIAxes_2, 'Đa thức nội suy', 'Dữ liệu thực');
        grid(app.UIAxes_2, 'on');
        hold(app.UIAxes_2, 'off');

        end

        % Button pushed function: DrawButton_3
        function DrawButton_3Pushed(app, event)
            % Get x and y data from edit fields
            x = str2num(app.xhoiquy.Value); %#ok<ST2NM>
            y = str2num(app.yhoiquy.Value); %#ok<ST2NM>
            
            method = app.nhappphoiquy.Value;
            
            switch method
                case 'Tuyến tính'
                    [p, yfit, equation] = linear_regression(x, y);
                case 'Hàm mũ'
                    [p, yfit, equation] = exponential_regression(x, y);
                case 'Logarit'
                    [p, yfit, equation] = logarithmic_regression(x, y);
            end
            
            app.kqpthoiquy.Value = equation;
            
            plot(app.UIAxes_3, x, y, 'o', x, yfit, '-');
            title(app.UIAxes_3, 'Đồ thị hàm số');
            xlabel(app.UIAxes_3, 'X');
            ylabel(app.UIAxes_3, 'Y');
            
            % Get the value to predict from the edit field
            xval = str2double(app.gtrihoiquy.Value);
            
            switch method
                case 'Tuyến tính'
                    yval = polyval(p, xval);
                case 'Hàm mũ'
                    yval = exp(p(1) * xval + p(2));
                case 'Logarit'
                    yval = p(1) * log(xval) + p(2);
            end
            app.kqhoiquy.Value = num2str(yval);
        end

        % Button pushed function: TnhtheoXYButton
        function TnhtheoXYButtonPushed(app, event)
            x = str2num(app.xdaoham.Value); % Nhập dữ liệu x
            y = str2num(app.ydaoham.Value); % Nhập dữ liệu y
            h = str2double(app.buocnhaydaoham.Value);


            order = app.saisodh.Value; % Giá trị O(h) hoặc O(h^2)
            
            if strcmp(order, 'O(h)')
                order = 1; % Tương ứng với O(h)
            elseif strcmp(order, 'O(h^2)')
                order = 2; % Tương ứng với O(h²)
            end
            
            switch app.nhapppdh.Value
                case 'Xấp xỉ tiến'
                    result = forward_difference(x, y, h, order);
                case 'Xấp xỉ lùi'
                    result = backward_difference(x, y, h, order);
                case 'Xấp xỉ trung tâm'
                    result = central_difference(x, y, h, order);
            end
            
            % Hiển thị kết quả
            app.kqdaoham.Value = num2str(result);
        end

        % Button pushed function: TnhtheohmsButton
        function TnhtheohmsButtonPushed(app, event)
            func_str = app.hsdaoham.Value; % Hàm số (chuỗi)
            x_val = str2double(app.xdaoham.Value); % Điểm cần tính đạo hàm
            h = str2double(app.buocnhaydaoham.Value); % Bước nhảy h
            method = app.nhapppdh.Value; % Phương pháp
            
            % Xử lý phương pháp
            switch method
                case 'Xấp xỉ tiến'
                    result = compute_derivative(func_str, x_val, h, 'forward');
                case 'Xấp xỉ lùi'
                    result = compute_derivative(func_str, x_val, h, 'backward');
                case 'Xấp xỉ trung tâm'
                    result = compute_derivative(func_str, x_val, h, 'central');
            end
            
            % Hiển thị kết quả
            app.kqdaoham.Value = num2str(result);
        end

        % Button pushed function: TnhtheohmButton
        function TnhtheohmButtonPushed(app, event)
                           %Đầu vào các dữ liệu
                fx=(app.Hamso_TichphanEditField.Value);
                % pp=app.PhngphptchphnDropDown;
                a=(app.Can_TichphanEditField.Value);
                b=(app.Can_TichphanEditField_2.Value);
                N=app.So_NEditField.Value;


 % function KQ = FuncTichPhan(fx, N, a, b, pp);
    fx = matlabFunction(str2sym(fx));

     switch app.PhngphptchphnDropDown
        case 'Hình thang'
            h = (b-a)/N;
            KQ = (h/2)*(fx(a)+fx(b)+2*sum(fx(a+h:h:b-h)));

        case 'Simpson 1/3'
            if mod(N,2) ~= 0
                N = N+1;
            end
            h = (b-a)/N;
            x = a +(0:N)*h;
            fx = feval(fx, x);
            KQ = (h/3)*(fx(1)+fx(N+1)+4*sum(fx(2:2:N))+2*sum(fx(3:2:N-1)));

        case 'Simpson 3/8'
            if mod(N,3) ~= 0
                N = N+1;
            end
            h = (b-a)/N;
            x = a+(0:N)*h;
            fx = feval(fx,x);
            KQ = (3*h/8)*(fx(1)+fx(N+1)+3*sum(fx(2:3:N))+3*sum(fx(3:3:N-1))+2*sum(fx(4:3:N-2)));

    end

        % end

                % K_Q = FuncTichPhan(fx,N,a,b,pp);
                K_Q=num2str(KQ);
                app.KtqutchphnEditField.Value=K_Q; % Hiển thị kết quả
        end

        % Button pushed function: TnhtheoXYButton_2
        function TnhtheoXYButton_2Pushed(app, event)
            x=str2num(app.DulieuX_TichphanEditField.Value);
            y=str2num(app.DulieuY_TichphanEditField);
            % pp=app.PhngphptchphnDropDown;
           
           
    switch app.PhngphptchphnDropDown
        case 'Hình thang'
            h = x(2) - x(1);
            KQ = (h/2)*(y(1)+y(length(y))+2*sum(y(2:length(y)-1)));

        case 'Simpson 1/3'
            if mod(length(y)-1, 2) == 0
            h = x(2) - x(1);
            KQ = (h/3)*(y(1)+y(length(y))+4*sum(y(2:2:length(y)-1))+2*sum(y(3:2:length(y)-2)));
         
            else
            app.KtqutchphnEditField.Value='số phần tử của x và y phải là chẵn';
            end
            

        case 'Simpson 3/8'
            if mod(length(y)-1, 3) == 0
            h = x(2) - x(1);
            KQ = (3*h/8)*(y(1)+y(length(y)+3*sum(y(2:3:length(y)-1)))+3*sum(y(3:3:length(y)-2))+2*sum(y(4:3:length(y)-3)));
            else
            app.KtqutchphnEditField.Value='số phần tử của x và y phải là chẵn';
            end
            
    end
% end


            % K_Q = FuncTichPhan_XY(x,y,pp);
            app.KtqutchphnEditField.Value=num2str(KQ);% Hiển thị kết quả
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DrawButton = uibutton(app.NghimTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpphngtrnhEditField.Value = 'x-sin(x)-0.25';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = {'Nhập khoảng phân ly'; ' nghiệm'};
            app.NhpkhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimEditField.Value = '-1,2';
            app.NhpsaischophpEditField_3Label = uilabel(app.NghimTab);
            app.NhpsaischophpEditField_3Label.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditField.Value = '0.005';
            app.KtqunghimEditField_3Label = uilabel(app.NghimTab);
            app.KtqunghimEditField_3Label.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'text');
            app.ChnphngphptmDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm';
            app.ChnphngphptmChiailpNewtontiptuynEditField = uidropdown(app.NghimTab);
            app.ChnphngphptmChiailpNewtontiptuynEditField.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.ChnphngphptmChiailpNewtontiptuynEditField.Value = 'Chia đôi';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.DrawButton_2 = uibutton(app.NisuyTab, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuxEditField.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditField.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditField.Value = '0.25';
            app.KtqunisuyEditField_3Label = uilabel(app.NisuyTab);
            app.KtqunisuyEditField_3Label.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField = uidropdown(app.NisuyTab);
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Items = {'Newton', 'Lagrange'};
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value = 'Newton';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HiquyTab);
            title(app.UIAxes_3, 'Đồ thị hàm số')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NhpdliuxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x';
            app.xhoiquy = uieditfield(app.HiquyTab, 'text');
            app.xhoiquy.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.yhoiquy = uieditfield(app.HiquyTab, 'text');
            app.yhoiquy.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.gtrihoiquy = uieditfield(app.HiquyTab, 'text');
            app.gtrihoiquy.Value = '0.25';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.kqpthoiquy = uieditfield(app.HiquyTab, 'text');
            app.KtqudonLabel = uilabel(app.HiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán ';
            app.kqhoiquy = uieditfield(app.HiquyTab, 'text');
            app.DrawButton_3 = uibutton(app.HiquyTab, 'push');
            app.DrawButton_3.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_3Pushed, true);
            app.DrawButton_3.Text = 'Draw';
            app.NhpphngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.NhpphngphphiquyDropDownLabel.Text = 'Nhập phương pháp hồi quy';
            app.nhappphoiquy = uidropdown(app.HiquyTab);
            app.nhappphoiquy.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.nhappphoiquy.Value = 'Tuyến tính';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TnhtheoXYButton = uibutton(app.ohmTab, 'push');
            app.TnhtheoXYButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtheoXYButtonPushed, true);
            app.TnhtheoXYButton.Text = 'Tính theo X Y';
            app.NhpdliuxEditField_2Label_2 = uilabel(app.ohmTab);
            app.NhpdliuxEditField_2Label_2.Text = 'Nhập dữ liệu x';
            app.xdaoham = uieditfield(app.ohmTab, 'text');
            app.xdaoham.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditField_2Label_2 = uilabel(app.ohmTab);
            app.NhpdliuyEditField_2Label_2.Text = 'Nhập dữ liệu y';
            app.ydaoham = uieditfield(app.ohmTab, 'text');
            app.ydaoham.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpbcnhyhLabel = uilabel(app.ohmTab);
            app.NhpbcnhyhLabel.Text = 'Nhập bước nhảy h';
            app.buocnhaydaoham = uieditfield(app.ohmTab, 'text');
            app.buocnhaydaoham.Value = '0.25';
            app.NhphmsLabel = uilabel(app.ohmTab);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.hsdaoham = uieditfield(app.ohmTab, 'text');
            app.KtquLabel = uilabel(app.ohmTab);
            app.KtquLabel.Text = 'Kết quả ';
            app.kqdaoham = uieditfield(app.ohmTab, 'text');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.TnhtheohmsButton = uibutton(app.ohmTab, 'push');
            app.TnhtheohmsButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtheohmsButtonPushed, true);
            app.TnhtheohmsButton.Text = 'Tính theo hàm số';
            app.SaisDropDownLabel = uilabel(app.ohmTab);
            app.SaisDropDownLabel.Text = 'Sai số';
            app.saisodh = uidropdown(app.ohmTab);
            app.saisodh.Items = {'O(h)', 'O(h^2)'};
            app.saisodh.Value = 'O(h)';
            app.NhpphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.NhpphngphpohmDropDownLabel.Text = 'Nhập phương pháp đạo hàm';
            app.nhapppdh = uidropdown(app.ohmTab);
            app.nhapppdh.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.nhapppdh.Value = 'Xấp xỉ tiến';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HoacLabel = uilabel(app.TchphnTab);
            app.HoacLabel.Text = 'Hoặc';
            app.TnhtheohmButton = uibutton(app.TchphnTab, 'push');
            app.TnhtheohmButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtheohmButtonPushed, true);
            app.TnhtheohmButton.Text = 'Tính theo hàm';
            app.TnhtheoXYButton_2 = uibutton(app.TchphnTab, 'push');
            app.TnhtheoXYButton_2.ButtonPushedFcn = createCallbackFcn(app, @TnhtheoXYButton_2Pushed, true);
            app.TnhtheoXYButton_2.Text = 'Tính theo XY';
            app.HmsEditfieldtextLabel = uilabel(app.TchphnTab);
            app.HmsEditfieldtextLabel.Text = 'Hàm số';
            app.Hamso_TichphanEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpNLabel = uilabel(app.TchphnTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.So_NEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận dưới a';
            app.Can_TichphanEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpcntnhtchphnLabel_2 = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnLabel_2.Text = 'Nhập cận trên b';
            app.Can_TichphanEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.DliuXEditfieldTexxtLabel = uilabel(app.TchphnTab);
            app.DliuXEditfieldTexxtLabel.Text = 'Dữ liệu X';
            app.DulieuX_TichphanEditField = uieditfield(app.TchphnTab, 'text');
            app.DliuYEditfieldTexxtLabel = uilabel(app.TchphnTab);
            app.DliuYEditfieldTexxtLabel.Text = 'Dữ liệu Y';
            app.DulieuY_TichphanEditField = uieditfield(app.TchphnTab, 'text');
            app.KtqutchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.KtqutchphnEditFieldLabel.Text = 'Kết quả tích phân';
            app.KtqutchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.PhngphptchphnDropDownLabel = uilabel(app.TchphnTab);
            app.PhngphptchphnDropDownLabel.Text = 'Phương pháp tích phân';
            app.PhngphptchphnDropDown = uidropdown(app.TchphnTab);
            app.PhngphptchphnDropDown.Items = {'Hình thang', 'Simpson 1/3 ', 'Simpson 3/8'};
            app.PhngphptchphnDropDown.Value = 'Hình thang';
            app.GiithiuNhmTab = uitab(app.TabGroup);
            app.GiithiuNhmTab.Title = 'Giới thiệu Nhóm';
            app.GiithiunhmLabel = uilabel(app.GiithiuNhmTab);
            app.GiithiunhmLabel.Text = 'Giới thiệu nhóm';
            app.ThnhvinnhmPanel = uipanel(app.GiithiuNhmTab);
            app.ThnhvinnhmPanel.Title = 'Thành viên nhóm';
            app.NguynLaiChnDuy22207018Label = uilabel(app.ThnhvinnhmPanel);
            app.NguynLaiChnDuy22207018Label.Text = '1. Nguyễn Lai Chấn Duy - 22207018';
            app.Thnht22207016Label = uilabel(app.ThnhvinnhmPanel);
            app.Thnht22207016Label.Text = '2. Đỗ Thành Đạt - 22207016';
            app.NguynVQucMinh22207059Label = uilabel(app.ThnhvinnhmPanel);
            app.NguynVQucMinh22207059Label.Text = '3. Nguyễn Vũ Quốc Minh- 22207059';
            app.GiaHuy22207038Label = uilabel(app.ThnhvinnhmPanel);
            app.GiaHuy22207038Label.Text = '4. Đỗ Gia Huy - 22207038';
            app.NhmtrngLabel = uilabel(app.ThnhvinnhmPanel);
            app.NhmtrngLabel.Text = '(Nhóm trưởng)';
            app.NhimvPanel = uipanel(app.GiithiuNhmTab);
            app.NhimvPanel.Title = 'Nhiệm vụ';
            app.BocoWordLabel = uilabel(app.NhimvPanel);
            app.BocoWordLabel.Text = 'Báo cáo Word:';
            app.CodeDesignLabel = uilabel(app.NhimvPanel);
            app.CodeDesignLabel.Text = 'Code + Design: ';
            app.PhnnghimnisuyChnDuyLabel = uilabel(app.NhimvPanel);
            app.PhnnghimnisuyChnDuyLabel.Text = 'Phần nghiệm, nội suy: Chấn Duy';
            app.PhnhiquyohmThnhtLabel = uilabel(app.NhimvPanel);
            app.PhnhiquyohmThnhtLabel.Text = 'Phần hồi quy, đạo hàm: Thành Đạt';
            app.PhntchphngiithiuQucMinhLabel = uilabel(app.NhimvPanel);
            app.PhntchphngiithiuQucMinhLabel.Text = 'Phần tích phân, giới thiệu: Quốc Minh';
            app.GiaHuyLabel = uilabel(app.NhimvPanel);
            app.GiaHuyLabel.Text = 'Gia Huy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Ten

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end